classdef Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RALauncherUIFigure              matlab.ui.Figure
        PlotSpectrumSpectraButton       matlab.ui.control.Button
        PerformBatchAnalysisButton      matlab.ui.control.Button
        RamanAnalyzerLabel              matlab.ui.control.Label
        ver17Label                      matlab.ui.control.Label
        PlotSpectraonSingleGraphSwitchLabel  matlab.ui.control.Label
        PlotSpectraonSingleGraphSwitch  matlab.ui.control.Switch
        ApplyCalibrationBeforePlottingSwitchLabel  matlab.ui.control.Label
        ApplyCalibrationBeforePlottingSwitch  matlab.ui.control.Switch
        EditField                       matlab.ui.control.NumericEditField
        CalibrationReferenceWavenumberLabel  matlab.ui.control.Label
        PreloadParametersforBiologicalTissueAnalysisSwitchLabel  matlab.ui.control.Label
        PreloadParametersforBiologicalTissueAnalysisSwitch  matlab.ui.control.Switch
        CLOSEButton                     matlab.ui.control.Button
        LicenseInformationButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        single_graph = 'On' % Description
        apply_calibration = 'Off' % Description
        wavenumber_ref = 520.5 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PerformBatchAnalysisButton
        function LauchAnalysis(app, event)

                spectrum_analysis=1;
                assignin('base','spectrum_analysis',spectrum_analysis);
                delete(app)
                 
        end

        % Close request function: RALauncherUIFigure
        function RALauncherUIFigureCloseRequest(app, event)
            delete(app)
            window_close=1;
            assignin('base','window_close',window_close);
        end

        % Button pushed function: PlotSpectrumSpectraButton
        function PlotSpectrumSpectraButtonPushed(app, event)
            PlotSpectra(app.single_graph,app.apply_calibration,app.wavenumber_ref)
        end

        % Value changed function: PlotSpectraonSingleGraphSwitch
        function PlotSpectraonSingleGraphSwitchValueChanged(app, event)
            value = app.PlotSpectraonSingleGraphSwitch.Value;
            app.single_graph = value;
            assignin('base','single_graph',value);
        end

        % Value changed function: 
        % ApplyCalibrationBeforePlottingSwitch
        function ApplyCalibrationBeforePlottingSwitchValueChanged(app, event)
            value = app.ApplyCalibrationBeforePlottingSwitch.Value;
            app.apply_calibration = value;
            assignin('base','apply_calibration',value);
            if isequal(app.ApplyCalibrationBeforePlottingSwitch.Value,'On')
                app.EditField.Enable = 'On';
                app.CalibrationReferenceWavenumberLabel.Enable = 'On';
            else
                app.EditField.Enable = 'Off';
                app.CalibrationReferenceWavenumberLabel.Enable = 'Off';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.wavenumber_ref = value;
            assignin('base','wavenumber_ref',value);
        end

        % Value changed function: 
        % PreloadParametersforBiologicalTissueAnalysisSwitch
        function PreloadParametersforBiologicalTissueAnalysisSwitchValueChanged(app, event)
            value = app.PreloadParametersforBiologicalTissueAnalysisSwitch.Value;
            assignin('base','load_bio_para',value);
        end

        % Button pushed function: CLOSEButton
        function close(app, event)
            closereq
            window_close=1;
            assignin('base','window_close',window_close)
        end

        % Button pushed function: LicenseInformationButton
        function LisenceInfo(app, event)
            msgbox({'Copyright (c) 2019, Hokkaido University' ; '' ; 'Redistribution and use in source and binary forms, with or without modification, are permitted provided that the following conditions are met:' ; '' ; '1. Redistributions of source code must retain the above copyright notice, this list of conditions and the following disclaimer.' ; '' ; '2. Redistributions in binary form must reproduce the above copyright notice, this list of conditions and the following disclaimer in the documentation and/or other materials provided with the distribution.' ; '' ; 'THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.'},'Lisence Information')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RALauncherUIFigure = uifigure('Visible', 'off');
            app.RALauncherUIFigure.Name = 'RA Launcher';
            app.RALauncherUIFigure.CloseRequestFcn = createCallbackFcn(app, @RALauncherUIFigureCloseRequest, true);
            app.PlotSpectrumSpectraButton = uibutton(app.RALauncherUIFigure, 'push');
            app.PlotSpectrumSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectrumSpectraButtonPushed, true);
            app.PlotSpectrumSpectraButton.Text = 'Plot Spectrum (Spectra)';
            app.PerformBatchAnalysisButton = uibutton(app.RALauncherUIFigure, 'push');
            app.PerformBatchAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LauchAnalysis, true);
            app.PerformBatchAnalysisButton.Text = 'Perform Batch Analysis';
            app.RamanAnalyzerLabel = uilabel(app.RALauncherUIFigure);
            app.RamanAnalyzerLabel.Text = 'Raman Analyzer';
            app.ver17Label = uilabel(app.RALauncherUIFigure);
            app.ver17Label.Text = 'ver. 1.7';
            app.PlotSpectraonSingleGraphSwitchLabel = uilabel(app.RALauncherUIFigure);
            app.PlotSpectraonSingleGraphSwitchLabel.Text = 'Plot Spectra on Single Graph';
            app.PlotSpectraonSingleGraphSwitch = uiswitch(app.RALauncherUIFigure, 'slider');
            app.PlotSpectraonSingleGraphSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSpectraonSingleGraphSwitchValueChanged, true);
            app.PlotSpectraonSingleGraphSwitch.Value = 'On';
            app.ApplyCalibrationBeforePlottingSwitchLabel = uilabel(app.RALauncherUIFigure);
            app.ApplyCalibrationBeforePlottingSwitchLabel.Text = 'Apply Calibration Before Plotting';
            app.ApplyCalibrationBeforePlottingSwitch = uiswitch(app.RALauncherUIFigure, 'slider');
            app.ApplyCalibrationBeforePlottingSwitch.ValueChangedFcn = createCallbackFcn(app, @ApplyCalibrationBeforePlottingSwitchValueChanged, true);
            app.EditField = uieditfield(app.RALauncherUIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = 520.5;
            app.CalibrationReferenceWavenumberLabel = uilabel(app.RALauncherUIFigure);
            app.CalibrationReferenceWavenumberLabel.Enable = 'off';
            app.CalibrationReferenceWavenumberLabel.Text = 'Calibration Reference Wavenumber';
            app.PreloadParametersforBiologicalTissueAnalysisSwitchLabel = uilabel(app.RALauncherUIFigure);
            app.PreloadParametersforBiologicalTissueAnalysisSwitchLabel.Text = 'Preload Parameters for Biological Tissue Analysis';
            app.PreloadParametersforBiologicalTissueAnalysisSwitch = uiswitch(app.RALauncherUIFigure, 'slider');
            app.PreloadParametersforBiologicalTissueAnalysisSwitch.ValueChangedFcn = createCallbackFcn(app, @PreloadParametersforBiologicalTissueAnalysisSwitchValueChanged, true);
            app.PreloadParametersforBiologicalTissueAnalysisSwitch.Value = 'On';
            app.CLOSEButton = uibutton(app.RALauncherUIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.LicenseInformationButton = uibutton(app.RALauncherUIFigure, 'push');
            app.LicenseInformationButton.ButtonPushedFcn = createCallbackFcn(app, @LisenceInfo, true);
            app.LicenseInformationButton.Text = 'License Information';
            % Show the figure after all components are created
            app.RALauncherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Launcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RALauncherUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RALauncherUIFigure)
        end
    end
end