classdef ParameterInputBiological < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParameterswithbiotempleteUIFigure  matlab.ui.Figure
        CalibrationWavenumberEditFieldLabel  matlab.ui.control.Label
        CalibrationWavenumberEditField  matlab.ui.control.NumericEditField
        CalibrationLabel                matlab.ui.control.Label
        SpectrumTrimStartandEndPointLabel  matlab.ui.control.Label
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        TrimSpectrumCheckBox            matlab.ui.control.CheckBox
        SavitzkyGolaySmoothingLabel     matlab.ui.control.Label
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        BackgroundRemovalLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        OrderEditField_2Label           matlab.ui.control.Label
        OrderEditField_2                matlab.ui.control.NumericEditField
        NormalizationLabel              matlab.ui.control.Label
        DropDown_2Label                 matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        BandsParametersLabel            matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        DisplayMarkersonPlotLabel       matlab.ui.control.Label
        CenterofGravityCheckBox         matlab.ui.control.CheckBox
        BandMaximaCheckBox              matlab.ui.control.CheckBox
        CurveFittingLabel               matlab.ui.control.Label
        RunCurveFittingCheckBox         matlab.ui.control.CheckBox
        ApplyLinearBackgroundRemovalCheckBox  matlab.ui.control.CheckBox
        FixSubcurvePeakLocationsCheckBox  matlab.ui.control.CheckBox
        ManuallyEnterSubcurvePeakLocationsCheckBox  matlab.ui.control.CheckBox
        FittingFunctionDropDownLabel    matlab.ui.control.Label
        FittingFunctionDropDown         matlab.ui.control.DropDown
        GaussianPartFractionEditFieldLabel  matlab.ui.control.Label
        GaussianPartFractionEditField   matlab.ui.control.NumericEditField
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        EditField_41                    matlab.ui.control.EditField
        EditField_42                    matlab.ui.control.EditField
        EditField_43                    matlab.ui.control.EditField
        EditField_44                    matlab.ui.control.EditField
        EditField_45                    matlab.ui.control.EditField
        EditField_46                    matlab.ui.control.EditField
        EditField_47                    matlab.ui.control.EditField
        EditField_48                    matlab.ui.control.EditField
        AdditionalpeaksLabel            matlab.ui.control.Label
        EditField_49                    matlab.ui.control.NumericEditField
        EditField_50                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.EditField
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_56                    matlab.ui.control.NumericEditField
        EditField_57                    matlab.ui.control.NumericEditField
        EditField_58                    matlab.ui.control.NumericEditField
        EditField_59                    matlab.ui.control.NumericEditField
        EditField_60                    matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        ChangeWorkingDirectoryLabel     matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        UnchecktoEXCLUDEBandLabel       matlab.ui.control.Label
        LocalMaximaCheckBox             matlab.ui.control.CheckBox
        RunAutoCalibrationCheckBox      matlab.ui.control.CheckBox
        PerformMeasurementonIsolatedBandsCheckBox  matlab.ui.control.CheckBox
        BandNameLabel                   matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        EndLabel                        matlab.ui.control.Label
        ExpectedPeakWavenumberLabel     matlab.ui.control.Label
        SmoothFactor01Label             matlab.ui.control.Label
        ExpectedBandWidthHWHMLabel      matlab.ui.control.Label
        EditField_61                    matlab.ui.control.NumericEditField
        EditField_62                    matlab.ui.control.NumericEditField
        EditField_63                    matlab.ui.control.NumericEditField
        EditField_64                    matlab.ui.control.NumericEditField
        EditField_65                    matlab.ui.control.NumericEditField
        EditField_66                    matlab.ui.control.NumericEditField
        EditField_67                    matlab.ui.control.NumericEditField
        EditField_68                    matlab.ui.control.NumericEditField
        EditField_69                    matlab.ui.control.NumericEditField
        EditField_70                    matlab.ui.control.NumericEditField
        EditField_71                    matlab.ui.control.NumericEditField
        EditField_72                    matlab.ui.control.NumericEditField
        EditField_73                    matlab.ui.control.NumericEditField
        EditField_74                    matlab.ui.control.NumericEditField
        EditField_75                    matlab.ui.control.NumericEditField
        EditField_76                    matlab.ui.control.NumericEditField
        StartLabel_2                    matlab.ui.control.Label
        EndLabel_2                      matlab.ui.control.Label
        WavenumberRangeAreaCOGCalculationLabel  matlab.ui.control.Label
        WavenumberRangeBandIsolationLabel  matlab.ui.control.Label
        EditField_77                    matlab.ui.control.NumericEditField
        EditField_78                    matlab.ui.control.NumericEditField
        EditField_79                    matlab.ui.control.NumericEditField
        EditField_80                    matlab.ui.control.NumericEditField
        BandIsolationLabel              matlab.ui.control.Label
        PleasesetwavenumberrangeforbandisolationinthenextsectionLabel  matlab.ui.control.Label
        ReferenceWavenumberEditFieldLabel  matlab.ui.control.Label
        ReferenceWavenumberEditField    matlab.ui.control.NumericEditField
        FINISHButton                    matlab.ui.control.Button
        ABORTButton                     matlab.ui.control.Button
        ApplyCalibrationCheckBox        matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalibrationWavenumberEditField
        function wavenumber_input(app, event)
            val1 = app.CalibrationWavenumberEditField.Value;
            assignin('base','wavenumber_input',val1);
        end

        % Callback function
        function run_calib_check(app, event)
            val2 = double(app.RunCalibrationforEverySpectrumCheckBox.Value);
            assignin('base','cal',val2);
        end

        % Value changed function: StartEditField
        function tm_start(app, event)
            val3 = app.StartEditField.Value;
            assignin('base','start_tm',val3);
        end

        % Value changed function: EndEditField
        function tm_end(app, event)
            val4 = app.EndEditField.Value;
            assignin('base','end_tm',val4);
        end

        % Value changed function: TrimSpectrumCheckBox
        function tm_check(app, event)
            val5 = double(app.TrimSpectrumCheckBox.Value);
            assignin('base','trim',val5);
            if app.TrimSpectrumCheckBox.Value == 1
                app.StartEditField.Enable = 'On';
                app.StartEditFieldLabel.Enable = 'On';
                app.EndEditField.Enable = 'On';
                app.EndEditFieldLabel.Enable = 'On';
            else
                app.StartEditField.Enable = 'Off';
                app.StartEditFieldLabel.Enable = 'Off';
                app.EndEditField.Enable = 'Off';
                app.EndEditFieldLabel.Enable = 'Off';
            end
        end

        % Value changed function: OrderEditField
        function smt_order(app, event)
            val6 = app.OrderEditField.Value;
            assignin('base','order_smt',val6);
        end

        % Value changed function: WindowSizeEditField
        function smt_size(app, event)
            val7 = app.WindowSizeEditField.Value;
            assignin('base','size_smt',val7);
        end

        % Value changed function: DropDown
        function bgr_method(app, event)
            app.DropDown.ItemsData=[0 1];
            val8 = app.DropDown.Value;
            assignin('base','bg_rmv',val8);
        end

        % Value changed function: OrderEditField_2
        function bgr_order(app, event)
            val9 = app.OrderEditField_2.Value;
            assignin('base','order_bk',val9);
        end

        % Value changed function: DropDown_2
        function norm_method(app, event)
            app.DropDown_2.ItemsData=[1 2 3 4 5];
            val10 = app.DropDown_2.Value;
            assignin('base','norm_type',val10);
        end

        % Value changed function: EditField
        function po_set1(app, event)
            val11_1 = app.EditField.Value;
            assignin('base','pk1_1',val11_1);
        end

        % Value changed function: EditField_2
        function po_set2(app, event)
            val11_2 = app.EditField_2.Value;
            assignin('base','pk1_2',val11_2);
        end

        % Value changed function: EditField_3
        function po_set3(app, event)
            val11_3 = app.EditField_3.Value;
            assignin('base','pk1_3',val11_3);
        end

        % Value changed function: EditField_4
        function po_set4(app, event)
            val11_4 = app.EditField_4.Value;
            assignin('base','pk1_4',val11_4);
        end

        % Value changed function: EditField_5
        function po_set5(app, event)
            val11_5 = app.EditField_5.Value;
            assignin('base','pk1_5',val11_5);
        end

        % Value changed function: EditField_6
        function amdi_1(app, event)
            val12_1 = app.EditField_6.Value;
            assignin('base','pk2_1',val12_1);
        end

        % Value changed function: EditField_7
        function amdi_2(app, event)
            val12_2 = app.EditField_7.Value;
            assignin('base','pk2_2',val12_2);
        end

        % Value changed function: EditField_8
        function amdi_3(app, event)
            val12_3 = app.EditField_8.Value;
            assignin('base','pk2_3',val12_3);
        end

        % Value changed function: EditField_9
        function amdi_4(app, event)
            val12_4 = app.EditField_9.Value;
            assignin('base','pk2_4',val12_4);
        end

        % Value changed function: EditField_10
        function amdi_5(app, event)
            val12_5 = app.EditField_10.Value;
            assignin('base','pk2_5',val12_5);
        end

        % Value changed function: EditField_11
        function ch_1(app, event)
            val13_1 = app.EditField_11.Value;
            assignin('base','pk3_1',val13_1);
        end

        % Value changed function: EditField_12
        function ch_2(app, event)
            val13_2 = app.EditField_12.Value;
            assignin('base','pk3_2',val13_2);
        end

        % Value changed function: EditField_13
        function ch_3(app, event)
            val13_3 = app.EditField_13.Value;
            assignin('base','pk3_3',val13_3);
        end

        % Value changed function: EditField_14
        function ch_4(app, event)
            val13_4 = app.EditField_14.Value;
            assignin('base','pk3_4',val13_4);
        end

        % Value changed function: EditField_15
        function ch_5(app, event)
            val13_5 = app.EditField_15.Value;
            assignin('base','pk3_5',val13_5);
        end

        % Value changed function: EditField_16
        function amdiii_1(app, event)
            val14_1 = app.EditField_16.Value;
            assignin('base','pk4_1',val14_1);
        end

        % Value changed function: EditField_17
        function amdiii_2(app, event)
            val14_2 = app.EditField_17.Value;
            assignin('base','pk4_2',val14_2);
        end

        % Value changed function: EditField_18
        function amdiii_3(app, event)
            val14_3 = app.EditField_18.Value;
            assignin('base','pk4_3',val14_3);
        end

        % Value changed function: EditField_19
        function amdiii_4(app, event)
            val14_4 = app.EditField_19.Value;
            assignin('base','pk4_4',val14_4);
        end

        % Value changed function: EditField_20
        function amdiii_5(app, event)
            val14_5 = app.EditField_20.Value;
            assignin('base','pk4_5',val14_5);
        end

        % Value changed function: EditField_21
        function co_1(app, event)
            val15_1 = app.EditField_21.Value;
            assignin('base','pk5_1',val15_1);
        end

        % Value changed function: EditField_22
        function co_2(app, event)
            val15_2 = app.EditField_22.Value;
            assignin('base','pk5_2',val15_2);
        end

        % Value changed function: EditField_23
        function co_3(app, event)
            val15_3 = app.EditField_23.Value;
            assignin('base','pk5_3',val15_3);
        end

        % Value changed function: EditField_24
        function co_4(app, event)
            val15_4 = app.EditField_24.Value;
            assignin('base','pk5_4',val15_4);
        end

        % Value changed function: EditField_25
        function co_5(app, event)
            val15_5 = app.EditField_25.Value;
            assignin('base','pk5_5',val15_5);
        end

        % Value changed function: EditField_26
        function prohyp_1(app, event)
            val16_1 = app.EditField_26.Value;
            assignin('base','pk6_1',val16_1);
        end

        % Value changed function: EditField_27
        function prohyp_2(app, event)
            val16_2 = app.EditField_27.Value;
            assignin('base','pk6_2',val16_2);
        end

        % Value changed function: EditField_28
        function prohyp_3(app, event)
            val16_3 = app.EditField_28.Value;
            assignin('base','pk6_3',val16_3);
        end

        % Value changed function: EditField_29
        function prohyp_4(app, event)
            val16_4 = app.EditField_29.Value;
            assignin('base','pk6_4',val16_4);
        end

        % Value changed function: EditField_30
        function prohyp_5(app, event)
            val16_5 = app.EditField_30.Value;
            assignin('base','pk6_5',val16_5);
        end

        % Value changed function: EditField_31
        function pro_1(app, event)
            val17_1 = app.EditField_31.Value;
            assignin('base','pk7_1',val17_1);
        end

        % Value changed function: EditField_32
        function pro_2(app, event)
            val17_2 = app.EditField_32.Value;
            assignin('base','pk7_2',val17_2);
        end

        % Value changed function: EditField_33
        function pro_3(app, event)
            val17_3 = app.EditField_33.Value;
            assignin('base','pk7_3',val17_3);
        end

        % Value changed function: EditField_34
        function pro_4(app, event)
            val17_4 = app.EditField_34.Value;
            assignin('base','pk7_4',val17_4);
        end

        % Value changed function: EditField_35
        function pro_5(app, event)
            val17_5 = app.EditField_35.Value;
            assignin('base','pk7_5',val17_5);
        end

        % Value changed function: EditField_36
        function hyp_1(app, event)
            val18_1 = app.EditField_36.Value;
            assignin('base','pk8_1',val18_1);
        end

        % Value changed function: EditField_37
        function hyp_2(app, event)
            val18_2 = app.EditField_37.Value;
            assignin('base','pk8_2',val18_2);
        end

        % Value changed function: EditField_38
        function hyp_3(app, event)
            val18_3 = app.EditField_38.Value;
            assignin('base','pk8_3',val18_3);
        end

        % Value changed function: EditField_39
        function hyp_4(app, event)
            val18_4 = app.EditField_39.Value;
            assignin('base','pk8_4',val18_4);
        end

        % Value changed function: EditField_40
        function hyp_5(app, event)
            val18_5 = app.EditField_40.Value;
            assignin('base','pk8_5',val18_5);
        end

        % Value changed function: CenterofGravityCheckBox
        function cog(app, event)
            val19 = double(app.CenterofGravityCheckBox.Value);
            assignin('base','d1',val19);
        end

        % Value changed function: BandMaximaCheckBox
        function pk_max(app, event)
            val20 = double(app.BandMaximaCheckBox.Value);
            assignin('base','d2',val20);
        end

        % Callback function
        function shift_max(app, event)
            val21 = double(app.SelectedShiftMaximaCheckBox.Value);
            assignin('base','d4',val21);
        end

        % Callback function
        function sample_type_or(app, event)
            val22 = double(app.TurnONOverrideCheckBox.Value);
            assignin('base','override',val22);
            if val22==1
                val23a='Pure Organic';
                assignin('base','typea',val23a);
            else 
                val23a='';
                assignin('base','typea',val23a);
            end
        end

        % Callback function
        function sample_type(app, event)
            app.SampleTypeIfOverrideisONDropDown.ItemsData=[0 1];
            val23 = app.SampleTypeIfOverrideisONDropDown.Value;
            assignin('base','type',val23);
        end

        % Value changed function: RunCurveFittingCheckBox
        function run_cf_check(app, event)
            val24 = double(app.RunCurveFittingCheckBox.Value);
            if val24 == 0
                app.ApplyLinearBackgroundRemovalCheckBox.Enable = 'off';
                app.FixSubcurvePeakLocationsCheckBox.Enable = 'off';
                app.ManuallyEnterSubcurvePeakLocationsCheckBox.Enable = 'off';
                app.FittingFunctionDropDown.Enable ='off';
                app.FittingFunctionDropDownLabel.Enable ='off';
                app.GaussianPartFractionEditField.Enable ='off';
                app.GaussianPartFractionEditFieldLabel.Enable ='off';
                app.AlgorithmDropDown.Enable = 'off';
                app.AlgorithmDropDownLabel.Enable ='off';
            else
                app.ApplyLinearBackgroundRemovalCheckBox.Enable = 'on';
                app.FixSubcurvePeakLocationsCheckBox.Enable = 'on';
                app.ManuallyEnterSubcurvePeakLocationsCheckBox.Enable = 'on';
                app.FittingFunctionDropDown.Enable ='on';
                app.FittingFunctionDropDownLabel.Enable ='on';
                app.GaussianPartFractionEditField.Enable ='on';
                app.GaussianPartFractionEditFieldLabel.Enable ='on';
                app.AlgorithmDropDown.Enable = 'on';
                app.AlgorithmDropDownLabel.Enable ='on';
            end
            assignin('base','run_curve_fit',val24);
        end

        % Value changed function: 
        % ApplyLinearBackgroundRemovalCheckBox
        function linear_bgr_check(app, event)
            val25 = double(app.ApplyLinearBackgroundRemovalCheckBox.Value);
            assignin('base','remove_background',val25);
        end

        % Value changed function: FixSubcurvePeakLocationsCheckBox
        function fix_pk_loc_check(app, event)
            val26 = double(app.FixSubcurvePeakLocationsCheckBox.Value);
            if val26 == 0
                app.ManuallyEnterSubcurvePeakLocationsCheckBox.Value = 0;
                assignin('base','type_locs',0);
            end
            assignin('base','fix_location',val26);
        end

        % Value changed function: 
        % ManuallyEnterSubcurvePeakLocationsCheckBox
        function man_pk_loc_check(app, event)
            val27 = double(app.ManuallyEnterSubcurvePeakLocationsCheckBox.Value);
            if val27 == 1
                app.FixSubcurvePeakLocationsCheckBox.Value = 1;
                assignin('base','fix_location',1);
            end
            assignin('base','type_locs',val27);
        end

        % Value changed function: FittingFunctionDropDown
        function fit_func(app, event)
            app.FittingFunctionDropDown.ItemsData=[0 1 2];
            val28 = app.FittingFunctionDropDown.Value;
            if val28 == 0 || 1
                app.GaussianPartFractionEditField.Enable = 'Off';
                app.GaussianPartFractionEditFieldLabel.Enable = 'Off';
            end
            if val28 == 2
                app.GaussianPartFractionEditField.Enable = 'On';
                app.GaussianPartFractionEditFieldLabel.Enable = 'On';
            end
            assignin('base','run_type',val28);
        end

        % Value changed function: GaussianPartFractionEditField
        function gaus_frac(app, event)
            val29 = app.GaussianPartFractionEditField.Value;
            assignin('base','frac',val29);
        end

        % Value changed function: AlgorithmDropDown
        function fit_alg(app, event)
            app.AlgorithmDropDown.ItemsData=[0 1];
            val30 = app.AlgorithmDropDown.Value;
            assignin('base','algo',val30);
        end

        % Callback function
        function ok(app, event)
          delete(handles.gui);
        end

        % Callback function
        function pkn1(app, event)
            value = app.EditField_41.Value;
            assignin('base','pk1a',value);
        end

        % Value changing function: EditField_41
        function pknc1(app, event)
            changingValue = event.Value;
            assignin('base','pk1a',changingValue);
        end

        % Value changing function: EditField_42
        function pknc2(app, event)
            changingValue = event.Value;
            assignin('base','pk2a',changingValue);
        end

        % Value changing function: EditField_43
        function pknc3(app, event)
            changingValue = event.Value;
            assignin('base','pk3a',changingValue);
        end

        % Value changing function: EditField_44
        function pknc4(app, event)
            changingValue = event.Value;
            assignin('base','pk4a',changingValue);
        end

        % Value changing function: EditField_45
        function pknc5(app, event)
            changingValue = event.Value;
            assignin('base','pk5a',changingValue);
        end

        % Value changing function: EditField_46
        function pknc6(app, event)
            changingValue = event.Value;
            assignin('base','pk6a',changingValue);
        end

        % Value changing function: EditField_47
        function pknc7(app, event)
            changingValue = event.Value;
            assignin('base','pk7a',changingValue);
        end

        % Value changing function: EditField_48
        function pknc8(app, event)
            changingValue = event.Value;
            assignin('base','pk8a',changingValue);
        end

        % Value changing function: EditField_54
        function pknc9(app, event)
            changingValue = event.Value;
            assignin('base','pk9a',changingValue);
        end

        % Value changing function: EditField_60
        function pknc10(app, event)
            changingValue = event.Value;
            assignin('base','pk10a',changingValue);
        end

        % Value changed function: EditField_49
        function xtrpk1_1(app, event)
            value = app.EditField_49.Value;
            assignin('base','pk9_1',value);
        end

        % Value changed function: EditField_50
        function xtrpk1_2(app, event)
            value = app.EditField_50.Value;
            assignin('base','pk9_2',value);
        end

        % Value changed function: EditField_51
        function xtrpk1_3(app, event)
            value = app.EditField_51.Value;
            assignin('base','pk9_3',value);
        end

        % Value changed function: EditField_52
        function xtrpk1_4(app, event)
            value = app.EditField_52.Value;
            assignin('base','pk9_4',value);
        end

        % Value changed function: EditField_53
        function xtrpk1_5(app, event)
            value = app.EditField_53.Value;
            assignin('base','pk9_5',value);
        end

        % Value changed function: EditField_55
        function xtrpk2_1(app, event)
            value = app.EditField_55.Value;
            assignin('base','pk10_1',value);
        end

        % Value changed function: EditField_56
        function xtrpk2_2(app, event)
            value = app.EditField_56.Value;
            assignin('base','pk10_2',value);
        end

        % Value changed function: EditField_57
        function xtrpk2_3(app, event)
            value = app.EditField_57.Value;
            assignin('base','pk10_3',value);
        end

        % Value changed function: EditField_58
        function xtrpk2_4(app, event)
            value = app.EditField_58.Value;
            assignin('base','pk10_4',value);
        end

        % Value changed function: EditField_59
        function xtrpk2_5(app, event)
            value = app.EditField_59.Value;
            assignin('base','pk10_5',value);
        end

        % Button pushed function: BrowseButton
        function browse(app, event)
            newdir=uigetdir;
            assignin('base','newdir',newdir);
        end

        % Value changed function: CheckBox_10
        function pk1ck(app, event)
            value = app.CheckBox_10.Value;
            assignin('base','pk1c',value);
        end

        % Value changed function: CheckBox
        function pk2ck(app, event)
            value = app.CheckBox.Value;
            assignin('base','pk2c',value);
        end

        % Value changed function: CheckBox_2
        function pk3ck(app, event)
            value = app.CheckBox_2.Value;
            assignin('base','pk3c',value);
        end

        % Value changed function: CheckBox_3
        function pk4ck(app, event)
            value = app.CheckBox_3.Value;
            assignin('base','pk4c',value);
        end

        % Value changed function: CheckBox_4
        function pk5ck(app, event)
            value = app.CheckBox_4.Value;
            assignin('base','pk5c',value);
        end

        % Value changed function: CheckBox_5
        function pk6ck(app, event)
            value = app.CheckBox_5.Value;
            assignin('base','pk6c',value);
        end

        % Value changed function: CheckBox_6
        function pk7ck(app, event)
            value = app.CheckBox_6.Value;
            assignin('base','pk7c',value);
        end

        % Value changed function: CheckBox_7
        function pk8ck(app, event)
            value = app.CheckBox_7.Value;
            assignin('base','pk8c',value);
        end

        % Value changed function: CheckBox_8
        function pk9ck(app, event)
            value = app.CheckBox_8.Value;
            assignin('base','pk9c',value);
        end

        % Value changed function: CheckBox_9
        function pk10ck(app, event)
            value = app.CheckBox_9.Value;
            assignin('base','pk10c',value);
        end

        % Value changed function: LocalMaximaCheckBox
        function loc_maxima(app, event)
            value = app.LocalMaximaCheckBox.Value;
            assignin('base','d3',value);
        end

        % Value changed function: RunAutoCalibrationCheckBox
        function auto_calib_check(app, event)
            value = app.RunAutoCalibrationCheckBox.Value;
            assignin('base','auto_cal',value);
            if value == 0
                app.CalibrationWavenumberEditField.Enable = 'On';
                app.CalibrationWavenumberEditFieldLabel.Enable = 'On';
            else
                app.CalibrationWavenumberEditField.Enable = 'Off';
                app.CalibrationWavenumberEditFieldLabel.Enable = 'Off';
            end
        end

        % Value changed function: 
        % PerformMeasurementonIsolatedBandsCheckBox
        function pkana(app, event)
            value = app.PerformMeasurementonIsolatedBandsCheckBox.Value;
            assignin('base','pkana',value);
        end

        % Value changed function: ReferenceWavenumberEditField
        function ReferenceWavenumberEditFieldValueChanged(app, event)
            value = app.ReferenceWavenumberEditField.Value;
            assignin('base','wavenumber_ref',value);
        end

        % Value changed function: EditField_61
        function EditField_61ValueChanged(app, event)
            value = app.EditField_61.Value;
            assignin('base','pk1_6',value);
        end

        % Value changed function: EditField_62
        function EditField_62ValueChanged(app, event)
            value = app.EditField_62.Value;
            assignin('base','pk1_7',value);
        end

        % Value changed function: EditField_63
        function EditField_63ValueChanged(app, event)
            value = app.EditField_63.Value;
            assignin('base','pk2_6',value);
        end

        % Value changed function: EditField_64
        function EditField_64ValueChanged(app, event)
            value = app.EditField_64.Value;
            assignin('base','pk2_7',value);
        end

        % Value changed function: EditField_65
        function EditField_65ValueChanged(app, event)
            value = app.EditField_65.Value;
            assignin('base','pk3_6',value);
        end

        % Value changed function: EditField_66
        function EditField_66ValueChanged(app, event)
            value = app.EditField_66.Value;
            assignin('base','pk3_7',value);
        end

        % Value changed function: EditField_67
        function EditField_67ValueChanged(app, event)
            value = app.EditField_67.Value;
            assignin('base','pk4_6',value);
        end

        % Value changed function: EditField_68
        function EditField_68ValueChanged(app, event)
            value = app.EditField_68.Value;
            assignin('base','pk4_7',value);
        end

        % Value changed function: EditField_69
        function EditField_69ValueChanged(app, event)
            value = app.EditField_69.Value;
            assignin('base','pk5_6',value);
        end

        % Value changed function: EditField_70
        function EditField_70ValueChanged(app, event)
            value = app.EditField_70.Value;
            assignin('base','pk5_7',value);
        end

        % Value changed function: EditField_71
        function EditField_71ValueChanged(app, event)
            value = app.EditField_71.Value;
            assignin('base','pk6_6',value);
        end

        % Value changed function: EditField_72
        function EditField_72ValueChanged(app, event)
            value = app.EditField_72.Value;
            assignin('base','pk6_7',value);
        end

        % Value changed function: EditField_73
        function EditField_73ValueChanged(app, event)
            value = app.EditField_73.Value;
            assignin('base','pk7_6',value);
        end

        % Value changed function: EditField_74
        function EditField_74ValueChanged(app, event)
            value = app.EditField_74.Value;
            assignin('base','pk7_7',value);
        end

        % Value changed function: EditField_75
        function EditField_75ValueChanged(app, event)
            value = app.EditField_75.Value;
            assignin('base','pk8_6',value);
        end

        % Value changed function: EditField_76
        function EditField_76ValueChanged(app, event)
            value = app.EditField_76.Value;
            assignin('base','pk8_7',value);
        end

        % Value changed function: EditField_77
        function EditField_77ValueChanged(app, event)
            value = app.EditField_77.Value;
            assignin('base','pk9_6',value);
        end

        % Value changed function: EditField_78
        function EditField_78ValueChanged(app, event)
            value = app.EditField_78.Value;
            assignin('base','pk9_7',value);
        end

        % Value changed function: EditField_79
        function EditField_79ValueChanged(app, event)
            value = app.EditField_79.Value;
            assignin('base','pk10_6',value);
        end

        % Value changed function: EditField_80
        function EditField_80ValueChanged(app, event)
            value = app.EditField_80.Value;
            assignin('base','pk10_7',value);
        end

        % Button pushed function: FINISHButton
        function finish(app, event)
               closereq
        end

        % Button pushed function: ABORTButton
        function abort(app, event)
            abort=1;
            assignin('base','abort',abort);
            closereq
        end

        % Value changed function: ApplyCalibrationCheckBox
        function cal_check(app, event)
            value = app.ApplyCalibrationCheckBox.Value;
            assignin("base",'cal',value);
            if app.ApplyCalibrationCheckBox.Value == 1
                app.RunAutoCalibrationCheckBox.Enable = 'On';
                app.ReferenceWavenumberEditField.Enable = 'On';
                app.ReferenceWavenumberEditFieldLabel.Enable = 'On';
                if app.RunAutoCalibrationCheckBox.Value == 0                  
                    app.CalibrationWavenumberEditField.Enable = 'On';
                    app.CalibrationWavenumberEditFieldLabel.Enable = 'On';
                else                   
                    app.CalibrationWavenumberEditField.Enable = 'Off';
                    app.CalibrationWavenumberEditFieldLabel.Enable = 'Off';
                end            
            else
                app.ReferenceWavenumberEditField.Enable = 'Off';
                app.ReferenceWavenumberEditFieldLabel.Enable = 'Off';
                app.CalibrationWavenumberEditField.Enable = 'Off';
                app.CalibrationWavenumberEditFieldLabel.Enable = 'Off';
                app.RunAutoCalibrationCheckBox.Enable = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParameterswithbiotempleteUIFigure = uifigure('Visible', 'off');
            app.ParameterswithbiotempleteUIFigure.Name = 'Parameters (with bio templete)';
            app.CalibrationWavenumberEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.CalibrationWavenumberEditFieldLabel.Enable = 'off';
            app.CalibrationWavenumberEditFieldLabel.Text = 'Calibration Wavenumber';
            app.CalibrationWavenumberEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.CalibrationWavenumberEditField.Limits = [0 Inf];
            app.CalibrationWavenumberEditField.ValueDisplayFormat = '%.1f';
            app.CalibrationWavenumberEditField.ValueChangedFcn = createCallbackFcn(app, @wavenumber_input, true);
            app.CalibrationWavenumberEditField.Enable = 'off';
            app.CalibrationWavenumberEditField.Value = 524.7452;
            app.CalibrationLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.CalibrationLabel.Text = 'Calibration';
            app.SpectrumTrimStartandEndPointLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.SpectrumTrimStartandEndPointLabel.Text = 'Spectrum Trim Start and End Point';
            app.StartEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueDisplayFormat = '%.f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @tm_start, true);
            app.StartEditField.Value = 800;
            app.EndEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EndEditField.Limits = [0 Inf];
            app.EndEditField.ValueDisplayFormat = '%.f';
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @tm_end, true);
            app.EndEditField.Value = 1800;
            app.TrimSpectrumCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.TrimSpectrumCheckBox.ValueChangedFcn = createCallbackFcn(app, @tm_check, true);
            app.TrimSpectrumCheckBox.Text = 'Trim Spectrum';
            app.TrimSpectrumCheckBox.Value = true;
            app.SavitzkyGolaySmoothingLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.SavitzkyGolaySmoothingLabel.Text = 'Savitzky-Golay Smoothing';
            app.OrderEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.OrderEditField.Limits = [1 Inf];
            app.OrderEditField.ValueDisplayFormat = '%.f';
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @smt_order, true);
            app.OrderEditField.Value = 2;
            app.WindowSizeEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.ValueDisplayFormat = '%.f';
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @smt_size, true);
            app.WindowSizeEditField.Value = 9;
            app.BackgroundRemovalLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.BackgroundRemovalLabel.Text = 'Background Removal';
            app.Label = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.ParameterswithbiotempleteUIFigure);
            app.DropDown.Items = {'Polynomial', 'Modified Polynomial'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @bgr_method, true);
            app.DropDown.Value = 'Modified Polynomial';
            app.OrderEditField_2Label = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.OrderEditField_2Label.Text = 'Order';
            app.OrderEditField_2 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.OrderEditField_2.Limits = [1 Inf];
            app.OrderEditField_2.ValueDisplayFormat = '%.f';
            app.OrderEditField_2.ValueChangedFcn = createCallbackFcn(app, @bgr_order, true);
            app.OrderEditField_2.Value = 4;
            app.NormalizationLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.NormalizationLabel.Text = 'Normalization';
            app.DropDown_2Label = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.DropDown_2Label.Text = '';
            app.DropDown_2 = uidropdown(app.ParameterswithbiotempleteUIFigure);
            app.DropDown_2.Items = {'Vectoring', 'SNV', 'Min-Max', 'Reference Peak', 'No Normalization'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @norm_method, true);
            app.DropDown_2.Value = 'SNV';
            app.BandsParametersLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.BandsParametersLabel.Text = 'Bands Parameters';
            app.EditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @po_set1, true);
            app.EditField.Value = 930;
            app.EditField_2 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%.1f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @po_set2, true);
            app.EditField_2.Value = 980;
            app.EditField_3 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%.1f';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @po_set3, true);
            app.EditField_3.Value = 960;
            app.EditField_4 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%.1f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @po_set4, true);
            app.EditField_4.Value = 0.99;
            app.EditField_5 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueDisplayFormat = '%.1f';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @po_set5, true);
            app.EditField_5.Value = 30;
            app.EditField_6 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.ValueDisplayFormat = '%.1f';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @amdi_1, true);
            app.EditField_6.Value = 1550;
            app.EditField_7 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.ValueDisplayFormat = '%.1f';
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @amdi_2, true);
            app.EditField_7.Value = 1750;
            app.EditField_8 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.ValueDisplayFormat = '%.1f';
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @amdi_3, true);
            app.EditField_8.Value = 1670;
            app.EditField_9 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.ValueDisplayFormat = '%.1f';
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @amdi_4, true);
            app.EditField_9.Value = 0.8;
            app.EditField_10 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_10.Limits = [0 Inf];
            app.EditField_10.ValueDisplayFormat = '%.1f';
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @amdi_5, true);
            app.EditField_10.Value = 50;
            app.EditField_11 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.ValueDisplayFormat = '%.1f';
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @ch_1, true);
            app.EditField_11.Value = 1430;
            app.EditField_12 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_12.Limits = [0 Inf];
            app.EditField_12.ValueDisplayFormat = '%.1f';
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @ch_2, true);
            app.EditField_12.Value = 1540;
            app.EditField_13 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_13.Limits = [0 Inf];
            app.EditField_13.ValueDisplayFormat = '%.1f';
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @ch_3, true);
            app.EditField_13.Value = 1454;
            app.EditField_14 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_14.Limits = [0 Inf];
            app.EditField_14.ValueDisplayFormat = '%.1f';
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @ch_4, true);
            app.EditField_14.Value = 0.8;
            app.EditField_15 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_15.Limits = [0 Inf];
            app.EditField_15.ValueDisplayFormat = '%.1f';
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @ch_5, true);
            app.EditField_15.Value = 30;
            app.EditField_16 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_16.Limits = [0 Inf];
            app.EditField_16.ValueDisplayFormat = '%.1f';
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @amdiii_1, true);
            app.EditField_16.Value = 1220;
            app.EditField_17 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_17.Limits = [0 Inf];
            app.EditField_17.ValueDisplayFormat = '%.1f';
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @amdiii_2, true);
            app.EditField_17.Value = 1455;
            app.EditField_18 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_18.Limits = [0 Inf];
            app.EditField_18.ValueDisplayFormat = '%.1f';
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @amdiii_3, true);
            app.EditField_18.Value = 1270;
            app.EditField_19 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_19.Limits = [0 Inf];
            app.EditField_19.ValueDisplayFormat = '%.1f';
            app.EditField_19.ValueChangedFcn = createCallbackFcn(app, @amdiii_4, true);
            app.EditField_19.Value = 0.8;
            app.EditField_20 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_20.Limits = [0 Inf];
            app.EditField_20.ValueDisplayFormat = '%.1f';
            app.EditField_20.ValueChangedFcn = createCallbackFcn(app, @amdiii_5, true);
            app.EditField_20.Value = 60;
            app.EditField_21 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_21.Limits = [0 Inf];
            app.EditField_21.ValueDisplayFormat = '%.1f';
            app.EditField_21.ValueChangedFcn = createCallbackFcn(app, @co_1, true);
            app.EditField_21.Value = 1060;
            app.EditField_22 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_22.Limits = [0 Inf];
            app.EditField_22.ValueDisplayFormat = '%.1f';
            app.EditField_22.ValueChangedFcn = createCallbackFcn(app, @co_2, true);
            app.EditField_22.Value = 1090;
            app.EditField_23 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_23.Limits = [0 Inf];
            app.EditField_23.ValueDisplayFormat = '%.1f';
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @co_3, true);
            app.EditField_23.Value = 1075;
            app.EditField_24 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_24.Limits = [0 Inf];
            app.EditField_24.ValueDisplayFormat = '%.1f';
            app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @co_4, true);
            app.EditField_24.Value = 0.8;
            app.EditField_25 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_25.Limits = [0 Inf];
            app.EditField_25.ValueDisplayFormat = '%.1f';
            app.EditField_25.ValueChangedFcn = createCallbackFcn(app, @co_5, true);
            app.EditField_25.Value = 15;
            app.EditField_26 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_26.Limits = [0 Inf];
            app.EditField_26.ValueDisplayFormat = '%.1f';
            app.EditField_26.ValueChangedFcn = createCallbackFcn(app, @prohyp_1, true);
            app.EditField_26.Value = 835;
            app.EditField_27 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_27.Limits = [0 Inf];
            app.EditField_27.ValueDisplayFormat = '%.1f';
            app.EditField_27.ValueChangedFcn = createCallbackFcn(app, @prohyp_2, true);
            app.EditField_27.Value = 900;
            app.EditField_28 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_28.Limits = [0 Inf];
            app.EditField_28.ValueDisplayFormat = '%.1f';
            app.EditField_28.ValueChangedFcn = createCallbackFcn(app, @prohyp_3, true);
            app.EditField_28.Value = 855;
            app.EditField_29 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_29.Limits = [0 Inf];
            app.EditField_29.ValueDisplayFormat = '%.1f';
            app.EditField_29.ValueChangedFcn = createCallbackFcn(app, @prohyp_4, true);
            app.EditField_29.Value = 0.8;
            app.EditField_30 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_30.Limits = [0 Inf];
            app.EditField_30.ValueDisplayFormat = '%.1f';
            app.EditField_30.ValueChangedFcn = createCallbackFcn(app, @prohyp_5, true);
            app.EditField_30.Value = 30;
            app.EditField_31 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_31.Limits = [0 Inf];
            app.EditField_31.ValueDisplayFormat = '%.1f';
            app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @pro_1, true);
            app.EditField_31.Value = 840;
            app.EditField_32 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_32.Limits = [0 Inf];
            app.EditField_32.ValueDisplayFormat = '%.1f';
            app.EditField_32.ValueChangedFcn = createCallbackFcn(app, @pro_2, true);
            app.EditField_32.Value = 870;
            app.EditField_33 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_33.Limits = [0 Inf];
            app.EditField_33.ValueDisplayFormat = '%.1f';
            app.EditField_33.ValueChangedFcn = createCallbackFcn(app, @pro_3, true);
            app.EditField_33.Value = 853;
            app.EditField_34 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_34.Limits = [0 Inf];
            app.EditField_34.ValueDisplayFormat = '%.1f';
            app.EditField_34.ValueChangedFcn = createCallbackFcn(app, @pro_4, true);
            app.EditField_34.Value = 0.8;
            app.EditField_35 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_35.Limits = [0 Inf];
            app.EditField_35.ValueDisplayFormat = '%.1f';
            app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @pro_5, true);
            app.EditField_35.Value = 10;
            app.EditField_36 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_36.Limits = [0 Inf];
            app.EditField_36.ValueDisplayFormat = '%.1f';
            app.EditField_36.ValueChangedFcn = createCallbackFcn(app, @hyp_1, true);
            app.EditField_36.Value = 870;
            app.EditField_37 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_37.Limits = [0 Inf];
            app.EditField_37.ValueDisplayFormat = '%.1f';
            app.EditField_37.ValueChangedFcn = createCallbackFcn(app, @hyp_2, true);
            app.EditField_37.Value = 900;
            app.EditField_38 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_38.Limits = [0 Inf];
            app.EditField_38.ValueDisplayFormat = '%.1f';
            app.EditField_38.ValueChangedFcn = createCallbackFcn(app, @hyp_3, true);
            app.EditField_38.Value = 877;
            app.EditField_39 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_39.Limits = [0 Inf];
            app.EditField_39.ValueDisplayFormat = '%.1f';
            app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @hyp_4, true);
            app.EditField_39.Value = 0.8;
            app.EditField_40 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_40.Limits = [0 Inf];
            app.EditField_40.ValueDisplayFormat = '%.1f';
            app.EditField_40.ValueChangedFcn = createCallbackFcn(app, @hyp_5, true);
            app.EditField_40.Value = 10;
            app.DisplayMarkersonPlotLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.DisplayMarkersonPlotLabel.Text = 'Display Markers on Plot';
            app.CenterofGravityCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CenterofGravityCheckBox.ValueChangedFcn = createCallbackFcn(app, @cog, true);
            app.CenterofGravityCheckBox.Text = 'Center of Gravity';
            app.CenterofGravityCheckBox.Value = true;
            app.BandMaximaCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.BandMaximaCheckBox.ValueChangedFcn = createCallbackFcn(app, @pk_max, true);
            app.BandMaximaCheckBox.Text = 'Band Maxima';
            app.BandMaximaCheckBox.Value = true;
            app.CurveFittingLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.CurveFittingLabel.Text = 'Curve Fitting';
            app.RunCurveFittingCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.RunCurveFittingCheckBox.ValueChangedFcn = createCallbackFcn(app, @run_cf_check, true);
            app.RunCurveFittingCheckBox.Text = 'Run Curve Fitting?';
            app.RunCurveFittingCheckBox.Value = true;
            app.ApplyLinearBackgroundRemovalCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.ApplyLinearBackgroundRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @linear_bgr_check, true);
            app.ApplyLinearBackgroundRemovalCheckBox.Text = 'Apply Linear Background Removal?';
            app.ApplyLinearBackgroundRemovalCheckBox.Value = true;
            app.FixSubcurvePeakLocationsCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.FixSubcurvePeakLocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @fix_pk_loc_check, true);
            app.FixSubcurvePeakLocationsCheckBox.Text = 'Fix Sub-curve Peak Locations?';
            app.ManuallyEnterSubcurvePeakLocationsCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.ManuallyEnterSubcurvePeakLocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @man_pk_loc_check, true);
            app.ManuallyEnterSubcurvePeakLocationsCheckBox.Text = 'Manually Enter Sub-curve Peak Locations?';
            app.FittingFunctionDropDownLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.FittingFunctionDropDownLabel.Text = 'Fitting Function';
            app.FittingFunctionDropDown = uidropdown(app.ParameterswithbiotempleteUIFigure);
            app.FittingFunctionDropDown.Items = {'Gaussian', 'Lorentzian', 'G-L Mix'};
            app.FittingFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @fit_func, true);
            app.FittingFunctionDropDown.Value = 'G-L Mix';
            app.GaussianPartFractionEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.GaussianPartFractionEditFieldLabel.Text = 'Gaussian Part Fraction';
            app.GaussianPartFractionEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.GaussianPartFractionEditField.Limits = [0 1];
            app.GaussianPartFractionEditField.ValueDisplayFormat = '%.1f';
            app.GaussianPartFractionEditField.ValueChangedFcn = createCallbackFcn(app, @gaus_frac, true);
            app.GaussianPartFractionEditField.Value = 0.7;
            app.AlgorithmDropDownLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.ParameterswithbiotempleteUIFigure);
            app.AlgorithmDropDown.Items = {'Trust-Region-Reflective', 'Levenberg-Marquardt'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @fit_alg, true);
            app.AlgorithmDropDown.Value = 'Trust-Region-Reflective';
            app.Label_2 = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.Label_2.Text = 'Click ''FINISH'' to Resume Analysis.            ';
            app.EditField_41 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_41.ValueChangingFcn = createCallbackFcn(app, @pknc1, true);
            app.EditField_41.Value = 'Phosphate v1';
            app.EditField_42 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_42.ValueChangingFcn = createCallbackFcn(app, @pknc2, true);
            app.EditField_42.Value = 'Amide I';
            app.EditField_43 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_43.ValueChangingFcn = createCallbackFcn(app, @pknc3, true);
            app.EditField_43.Value = 'Methylene (CH2)';
            app.EditField_44 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_44.ValueChangingFcn = createCallbackFcn(app, @pknc4, true);
            app.EditField_44.Value = 'Amide III';
            app.EditField_45 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_45.ValueChangingFcn = createCallbackFcn(app, @pknc5, true);
            app.EditField_45.Value = 'Carb. Apt. (CO3)';
            app.EditField_46 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_46.ValueChangingFcn = createCallbackFcn(app, @pknc6, true);
            app.EditField_46.Value = 'Pro/Hyp ';
            app.EditField_47 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_47.ValueChangingFcn = createCallbackFcn(app, @pknc7, true);
            app.EditField_47.Value = 'Proline';
            app.EditField_48 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_48.ValueChangingFcn = createCallbackFcn(app, @pknc8, true);
            app.EditField_48.Value = 'Hydroxyproline';
            app.AdditionalpeaksLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.AdditionalpeaksLabel.Text = 'Additional peaks';
            app.EditField_49 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_49.Limits = [0 Inf];
            app.EditField_49.ValueDisplayFormat = '%.1f';
            app.EditField_49.ValueChangedFcn = createCallbackFcn(app, @xtrpk1_1, true);
            app.EditField_50 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_50.Limits = [0 Inf];
            app.EditField_50.ValueDisplayFormat = '%.1f';
            app.EditField_50.ValueChangedFcn = createCallbackFcn(app, @xtrpk1_2, true);
            app.EditField_51 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_51.Limits = [0 Inf];
            app.EditField_51.ValueDisplayFormat = '%.1f';
            app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @xtrpk1_3, true);
            app.EditField_52 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_52.Limits = [0 Inf];
            app.EditField_52.ValueDisplayFormat = '%.1f';
            app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @xtrpk1_4, true);
            app.EditField_53 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_53.Limits = [0 Inf];
            app.EditField_53.ValueDisplayFormat = '%.1f';
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @xtrpk1_5, true);
            app.EditField_54 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_54.ValueChangingFcn = createCallbackFcn(app, @pknc9, true);
            app.EditField_55 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_55.Limits = [0 Inf];
            app.EditField_55.ValueDisplayFormat = '%.1f';
            app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @xtrpk2_1, true);
            app.EditField_56 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_56.Limits = [0 Inf];
            app.EditField_56.ValueDisplayFormat = '%.1f';
            app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @xtrpk2_2, true);
            app.EditField_57 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_57.Limits = [0 Inf];
            app.EditField_57.ValueDisplayFormat = '%.1f';
            app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @xtrpk2_3, true);
            app.EditField_58 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_58.Limits = [0 Inf];
            app.EditField_58.ValueDisplayFormat = '%.1f';
            app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @xtrpk2_4, true);
            app.EditField_59 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_59.Limits = [0 Inf];
            app.EditField_59.ValueDisplayFormat = '%.1f';
            app.EditField_59.ValueChangedFcn = createCallbackFcn(app, @xtrpk2_5, true);
            app.EditField_60 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'text');
            app.EditField_60.ValueChangingFcn = createCallbackFcn(app, @pknc10, true);
            app.BrowseButton = uibutton(app.ParameterswithbiotempleteUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseButton.Text = 'Browse';
            app.ChangeWorkingDirectoryLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.ChangeWorkingDirectoryLabel.Text = 'Change Working Directory ';
            app.CheckBox_10 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @pk1ck, true);
            app.CheckBox_10.Text = '';
            app.CheckBox_10.Value = true;
            app.CheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @pk2ck, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @pk3ck, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @pk4ck, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @pk5ck, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @pk6ck, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @pk7ck, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @pk8ck, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @pk9ck, true);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @pk10ck, true);
            app.CheckBox_9.Text = '';
            app.UnchecktoEXCLUDEBandLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.UnchecktoEXCLUDEBandLabel.Text = '(Uncheck to EXCLUDE Band)';
            app.LocalMaximaCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.LocalMaximaCheckBox.ValueChangedFcn = createCallbackFcn(app, @loc_maxima, true);
            app.LocalMaximaCheckBox.Text = 'Local Maxima';
            app.LocalMaximaCheckBox.Value = true;
            app.RunAutoCalibrationCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.RunAutoCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @auto_calib_check, true);
            app.RunAutoCalibrationCheckBox.Text = 'Run Auto Calibration';
            app.RunAutoCalibrationCheckBox.Value = true;
            app.PerformMeasurementonIsolatedBandsCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.PerformMeasurementonIsolatedBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @pkana, true);
            app.PerformMeasurementonIsolatedBandsCheckBox.Text = 'Perform Measurement on Isolated Bands?';
            app.PerformMeasurementonIsolatedBandsCheckBox.Value = true;
            app.BandNameLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.BandNameLabel.Text = 'Band Name';
            app.StartLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.StartLabel.Text = 'Start';
            app.EndLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.EndLabel.Text = 'End';
            app.ExpectedPeakWavenumberLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.ExpectedPeakWavenumberLabel.Text = {'Expected Peak'; 'Wavenumber'};
            app.SmoothFactor01Label = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.SmoothFactor01Label.Text = {'Smooth'; 'Factor'; '(0 - 1)'};
            app.ExpectedBandWidthHWHMLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.ExpectedBandWidthHWHMLabel.Text = {'Expected'; 'Band Width '; '(HWHM)'};
            app.EditField_61 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_61.Limits = [0 Inf];
            app.EditField_61.ValueDisplayFormat = '%.1f';
            app.EditField_61.ValueChangedFcn = createCallbackFcn(app, @EditField_61ValueChanged, true);
            app.EditField_61.Value = 880;
            app.EditField_62 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_62.Limits = [0 Inf];
            app.EditField_62.ValueDisplayFormat = '%.1f';
            app.EditField_62.ValueChangedFcn = createCallbackFcn(app, @EditField_62ValueChanged, true);
            app.EditField_62.Value = 1005;
            app.EditField_63 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_63.Limits = [0 Inf];
            app.EditField_63.ValueDisplayFormat = '%.1f';
            app.EditField_63.ValueChangedFcn = createCallbackFcn(app, @EditField_63ValueChanged, true);
            app.EditField_63.Value = 1500;
            app.EditField_64 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_64.Limits = [0 Inf];
            app.EditField_64.ValueDisplayFormat = '%.1f';
            app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @EditField_64ValueChanged, true);
            app.EditField_64.Value = 1800;
            app.EditField_65 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_65.Limits = [0 Inf];
            app.EditField_65.ValueDisplayFormat = '%.1f';
            app.EditField_65.ValueChangedFcn = createCallbackFcn(app, @EditField_65ValueChanged, true);
            app.EditField_65.Value = 1100;
            app.EditField_66 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_66.Limits = [0 Inf];
            app.EditField_66.ValueDisplayFormat = '%.1f';
            app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @EditField_66ValueChanged, true);
            app.EditField_66.Value = 1570;
            app.EditField_67 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_67.Limits = [0 Inf];
            app.EditField_67.ValueDisplayFormat = '%.1f';
            app.EditField_67.ValueChangedFcn = createCallbackFcn(app, @EditField_67ValueChanged, true);
            app.EditField_68 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_68.Limits = [0 Inf];
            app.EditField_68.ValueDisplayFormat = '%.1f';
            app.EditField_68.ValueChangedFcn = createCallbackFcn(app, @EditField_68ValueChanged, true);
            app.EditField_69 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_69.Limits = [0 Inf];
            app.EditField_69.ValueDisplayFormat = '%.1f';
            app.EditField_69.ValueChangedFcn = createCallbackFcn(app, @EditField_69ValueChanged, true);
            app.EditField_69.Value = 970;
            app.EditField_70 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_70.Limits = [0 Inf];
            app.EditField_70.ValueDisplayFormat = '%.1f';
            app.EditField_70.ValueChangedFcn = createCallbackFcn(app, @EditField_70ValueChanged, true);
            app.EditField_70.Value = 1170;
            app.EditField_71 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_71.Limits = [0 Inf];
            app.EditField_71.ValueDisplayFormat = '%.1f';
            app.EditField_71.ValueChangedFcn = createCallbackFcn(app, @EditField_71ValueChanged, true);
            app.EditField_71.Value = 815;
            app.EditField_72 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_72.Limits = [0 Inf];
            app.EditField_72.ValueDisplayFormat = '%.1f';
            app.EditField_72.ValueChangedFcn = createCallbackFcn(app, @EditField_72ValueChanged, true);
            app.EditField_72.Value = 915;
            app.EditField_73 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_73.Limits = [0 Inf];
            app.EditField_73.ValueDisplayFormat = '%.1f';
            app.EditField_73.ValueChangedFcn = createCallbackFcn(app, @EditField_73ValueChanged, true);
            app.EditField_74 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_74.Limits = [0 Inf];
            app.EditField_74.ValueDisplayFormat = '%.1f';
            app.EditField_74.ValueChangedFcn = createCallbackFcn(app, @EditField_74ValueChanged, true);
            app.EditField_75 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_75.Limits = [0 Inf];
            app.EditField_75.ValueDisplayFormat = '%.1f';
            app.EditField_75.ValueChangedFcn = createCallbackFcn(app, @EditField_75ValueChanged, true);
            app.EditField_76 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_76.Limits = [0 Inf];
            app.EditField_76.ValueDisplayFormat = '%.1f';
            app.EditField_76.ValueChangedFcn = createCallbackFcn(app, @EditField_76ValueChanged, true);
            app.StartLabel_2 = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.StartLabel_2.Text = 'Start';
            app.EndLabel_2 = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.EndLabel_2.Text = 'End';
            app.WavenumberRangeAreaCOGCalculationLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.WavenumberRangeAreaCOGCalculationLabel.Text = {'Wavenumber Range'; '(Area  & COG Calculation)'};
            app.WavenumberRangeBandIsolationLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.WavenumberRangeBandIsolationLabel.Text = {'Wavenumber Range'; '(Band Isolation)'};
            app.EditField_77 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_77.Limits = [0 Inf];
            app.EditField_77.ValueDisplayFormat = '%.1f';
            app.EditField_77.ValueChangedFcn = createCallbackFcn(app, @EditField_77ValueChanged, true);
            app.EditField_78 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_78.Limits = [0 Inf];
            app.EditField_78.ValueDisplayFormat = '%.1f';
            app.EditField_78.ValueChangedFcn = createCallbackFcn(app, @EditField_78ValueChanged, true);
            app.EditField_79 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_79.Limits = [0 Inf];
            app.EditField_79.ValueDisplayFormat = '%.1f';
            app.EditField_79.ValueChangedFcn = createCallbackFcn(app, @EditField_79ValueChanged, true);
            app.EditField_80 = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.EditField_80.Limits = [0 Inf];
            app.EditField_80.ValueDisplayFormat = '%.1f';
            app.EditField_80.ValueChangedFcn = createCallbackFcn(app, @EditField_80ValueChanged, true);
            app.BandIsolationLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.BandIsolationLabel.Text = 'Band Isolation';
            app.PleasesetwavenumberrangeforbandisolationinthenextsectionLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.PleasesetwavenumberrangeforbandisolationinthenextsectionLabel.Text = {'(Please set wavenumber range'; 'for band isolation in the next section)'};
            app.ReferenceWavenumberEditFieldLabel = uilabel(app.ParameterswithbiotempleteUIFigure);
            app.ReferenceWavenumberEditFieldLabel.Text = 'Reference Wavenumber';
            app.ReferenceWavenumberEditField = uieditfield(app.ParameterswithbiotempleteUIFigure, 'numeric');
            app.ReferenceWavenumberEditField.Limits = [0 Inf];
            app.ReferenceWavenumberEditField.ValueDisplayFormat = '%.1f';
            app.ReferenceWavenumberEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceWavenumberEditFieldValueChanged, true);
            app.ReferenceWavenumberEditField.Value = 520.5;
            app.FINISHButton = uibutton(app.ParameterswithbiotempleteUIFigure, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @finish, true);
            app.FINISHButton.Text = 'FINISH';
            app.ABORTButton = uibutton(app.ParameterswithbiotempleteUIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @abort, true);
            app.ABORTButton.Icon = 'AbortButton.png';
            app.ABORTButton.Text = 'ABORT';
            app.ApplyCalibrationCheckBox = uicheckbox(app.ParameterswithbiotempleteUIFigure);
            app.ApplyCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @cal_check, true);
            app.ApplyCalibrationCheckBox.Text = 'Apply Calibration';
            app.ApplyCalibrationCheckBox.Value = true;
            % Show the figure after all components are created
            app.ParameterswithbiotempleteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterInputBiological

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParameterswithbiotempleteUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParameterswithbiotempleteUIFigure)
        end
    end
end